classdef BitPlayer_2P < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BitPlayer_2PUIFigure        matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        Load2PButton                matlab.ui.control.Button
        ROIButton                   matlab.ui.control.Button
        ROIButton_2                 matlab.ui.control.Button
        ROIEditFieldLabel           matlab.ui.control.Label
        ROIEditField                matlab.ui.control.NumericEditField
        SelectLabel                 matlab.ui.control.Label
        SelectEditField             matlab.ui.control.EditField
        DeselectEditFieldLabel      matlab.ui.control.Label
        DeselectEditField           matlab.ui.control.EditField
        HzEditFieldLabel            matlab.ui.control.Label
        HzEditField                 matlab.ui.control.NumericEditField
        F0framesEditFieldLabel      matlab.ui.control.Label
        F0framesEditField           matlab.ui.control.EditField
        ApplyChangestodFFButton     matlab.ui.control.StateButton
        DetrendButton_2             matlab.ui.control.StateButton
        LowcutfileterButton         matlab.ui.control.StateButton
        OffsetButton_2              matlab.ui.control.StateButton
        ZscoredButton_2             matlab.ui.control.StateButton
        AveragedbyStimButton        matlab.ui.control.Button
        ShowresponseselectedButton  matlab.ui.control.Button
        ColormapAllButton           matlab.ui.control.Button
        TuningselectedButton        matlab.ui.control.Button
        ROImapButton                matlab.ui.control.Button
        BootstrapFitButton          matlab.ui.control.Button
        FileLabel                   matlab.ui.control.Label
        DebugButton                 matlab.ui.control.Button
        SaveasButton                matlab.ui.control.Button
        newbootstrapCheckBox        matlab.ui.control.CheckBox
        ImagesizeLabel              matlab.ui.control.Label
        Imagesize                   matlab.ui.control.NumericEditField
        RightPanel                  matlab.ui.container.Panel
        UIAxes                      matlab.ui.control.UIAxes
        UIAxes_2                    matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        BitPlayer_Analysys %Main app object
        
    end
    
    properties (Access = public)
        mainvar
        SaveData
        SaveTimestamps
        recobj
        sobj
        ParamsSave
        imgobj
        n = 1;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % Sharing variables with BitPlayer_Analysis.mlapp
            app.BitPlayer_Analysys = mainapp;
            
            % Check parameters
            if isfield(mainapp.imgobj, 'F')
                % imaging data is alread extracted.
                app.mainvar = mainapp.mainvar;
                app.SaveData = mainapp.SaveData;
                app.SaveTimestamps = mainapp.SaveTimestamps;
                app.recobj = mainapp.recobj;
                app.sobj = mainapp.sobj;
                app.ParamsSave = mainapp.ParamsSave;
                app.imgobj = mainapp.imgobj;
                
%                 %Get_stim_timing
%                 Get_stim_timing(app);
%                 
%                 %plot check
%                 if ~isempty(app.imgobj.dFF)
%                     Plot_dFF_next(app, 0)
%                     Plot_dFF_selectROIs(app)
%                 end
%                 
%                 
%                 %F0 check
%                 if isfield(app.imgobj, 'f0')
%                     app.F0_end.Value = app.imgobj.f0;
%                 else
%                     errordlg('F0 not defined')
%                     app.NBA_Ana11.TwoPhotonButton.Enable = 'on';
%                     delete(app)
%                 end
            else
                %Reset buttion
                app.BitPlayer_Analysys.Open2PWindowButton.Enable = 'on';
                app.BitPlayer_Analysys.Open2PWindowButton.Value = 0;
                errordlg('Load DAQ & PTB first!!')
                delete(app)
            end


        end

        % Button pushed function: Load2PButton
        function Load2PButtonPushed(app, event)
           
        end

        % Button pushed function: DebugButton
        function DebugButtonPushed(app, event)
            assignin('base', "mainvar", app.mainvar)
            assignin('base', "SaveData", app.SaveData)
            assignin('base', "SaveTimestamps", app.SaveTimestamps)
            assignin('base', "recobj", app.recobj)
            assignin('base', "sobj", app.sobj)
            assignin('base', "ParamsSave", app.ParamsSave)
            assignin('base', "imgobj", app.imgobj)
        end

        % Close request function: BitPlayer_2PUIFigure
        function BitPlayer_2PUIFigureCloseRequest(app, event)
            % Enable the Open2P button in main app
            app.BitPlayer_Analysys.Open2PWindowButton.Enable = 'on';
            app.BitPlayer_Analysys.Open2PWindowButton.Value = 0;
            
            % Delete the BitPlayer_2P App
            delete(app)
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.BitPlayer_2PUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {800, 800};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {397, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BitPlayer_2PUIFigure = uifigure('Visible', 'off');
            app.BitPlayer_2PUIFigure.Name = 'BitPlayer_2P';
            app.BitPlayer_2PUIFigure.CloseRequestFcn = createCallbackFcn(app, @BitPlayer_2PUIFigureCloseRequest, true);
            app.BitPlayer_2PUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.BitPlayer_2PUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftPanel.Title = 'Menu';
            app.Load2PButton = uibutton(app.LeftPanel, 'push');
            app.Load2PButton.ButtonPushedFcn = createCallbackFcn(app, @Load2PButtonPushed, true);
            app.Load2PButton.Text = 'Load 2P';
            app.ROIButton = uibutton(app.LeftPanel, 'push');
            app.ROIButton.Text = ' ROI +';
            app.ROIButton_2 = uibutton(app.LeftPanel, 'push');
            app.ROIButton_2.Text = ' ROI -';
            app.ROIEditFieldLabel = uilabel(app.LeftPanel);
            app.ROIEditFieldLabel.Text = 'ROI #';
            app.ROIEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ROIEditField.Limits = [1 Inf];
            app.ROIEditField.Value = 1;
            app.SelectLabel = uilabel(app.LeftPanel);
            app.SelectLabel.Text = 'Select: ';
            app.SelectEditField = uieditfield(app.LeftPanel, 'text');
            app.SelectEditField.Value = '1';
            app.DeselectEditFieldLabel = uilabel(app.LeftPanel);
            app.DeselectEditFieldLabel.Text = 'Deselect:';
            app.DeselectEditField = uieditfield(app.LeftPanel, 'text');
            app.DeselectEditField.Value = '1';
            app.HzEditFieldLabel = uilabel(app.LeftPanel);
            app.HzEditFieldLabel.Text = 'Hz';
            app.HzEditField = uieditfield(app.LeftPanel, 'numeric');
            app.HzEditField.Limits = [0 Inf];
            app.HzEditField.Value = 0.005;
            app.F0framesEditFieldLabel = uilabel(app.LeftPanel);
            app.F0framesEditFieldLabel.Text = 'F0 frames: ';
            app.F0framesEditField = uieditfield(app.LeftPanel, 'text');
            app.F0framesEditField.Value = '1:100';
            app.ApplyChangestodFFButton = uibutton(app.LeftPanel, 'state');
            app.ApplyChangestodFFButton.Text = 'Apply Changes to dF/F';
            app.DetrendButton_2 = uibutton(app.LeftPanel, 'state');
            app.DetrendButton_2.Text = 'Detrend';
            app.LowcutfileterButton = uibutton(app.LeftPanel, 'state');
            app.LowcutfileterButton.Text = 'Low cut fileter';
            app.OffsetButton_2 = uibutton(app.LeftPanel, 'state');
            app.OffsetButton_2.Text = 'Offset';
            app.ZscoredButton_2 = uibutton(app.LeftPanel, 'state');
            app.ZscoredButton_2.Text = 'Z-scored';
            app.AveragedbyStimButton = uibutton(app.LeftPanel, 'push');
            app.AveragedbyStimButton.Text = 'Averaged by Stim';
            app.ShowresponseselectedButton = uibutton(app.LeftPanel, 'push');
            app.ShowresponseselectedButton.Text = 'Show response (selected)';
            app.ColormapAllButton = uibutton(app.LeftPanel, 'push');
            app.ColormapAllButton.Text = 'Color map (All)';
            app.TuningselectedButton = uibutton(app.LeftPanel, 'push');
            app.TuningselectedButton.Text = 'Tuning (selected)';
            app.ROImapButton = uibutton(app.LeftPanel, 'push');
            app.ROImapButton.Text = 'ROI map';
            app.BootstrapFitButton = uibutton(app.LeftPanel, 'push');
            app.BootstrapFitButton.Text = 'Bootstrap & Fit';
            app.FileLabel = uilabel(app.LeftPanel);
            app.FileLabel.Text = 'File:';
            app.DebugButton = uibutton(app.LeftPanel, 'push');
            app.DebugButton.ButtonPushedFcn = createCallbackFcn(app, @DebugButtonPushed, true);
            app.DebugButton.Text = 'Debug';
            app.SaveasButton = uibutton(app.LeftPanel, 'push');
            app.SaveasButton.Text = 'Save as...';
            app.newbootstrapCheckBox = uicheckbox(app.LeftPanel);
            app.newbootstrapCheckBox.Text = 'new bootstrap';
            app.ImagesizeLabel = uilabel(app.LeftPanel);
            app.ImagesizeLabel.Text = 'Image size:';
            app.Imagesize = uieditfield(app.LeftPanel, 'numeric');
            app.Imagesize.Limits = [1 Inf];
            app.Imagesize.Value = 320;
            app.RightPanel = uipanel(app.GridLayout);
            app.RightPanel.Title = 'View 2P Data';
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'ROI#  ')
            xlabel(app.UIAxes, 'Time (sec)')
            ylabel(app.UIAxes, 'dF/F0')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.RightPanel);
            title(app.UIAxes_2, 'Selected')
            xlabel(app.UIAxes_2, 'Time (sec)')
            ylabel(app.UIAxes_2, 'dF/F0')
            zlabel(app.UIAxes_2, 'Z')
            % Show the figure after all components are created
            app.BitPlayer_2PUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BitPlayer_2P(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.BitPlayer_2PUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.BitPlayer_2PUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BitPlayer_2PUIFigure)
        end
    end
end